classdef interfaz2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ventana2                     matlab.ui.Figure
        PagPrincipalButton           matlab.ui.control.Button
        RaicesdeEcuacionesLabel      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        MetodosDropDownLabel         matlab.ui.control.Label
        MetodosDropDown              matlab.ui.control.DropDown
        ButtonGroup                  matlab.ui.container.ButtonGroup
        MetododelaSecanteButton      matlab.ui.control.RadioButton
        MetododeNewtonRaphsonButton  matlab.ui.control.RadioButton
        PuntoFijoButton              matlab.ui.control.RadioButton
        RaicesMultiplesButton        matlab.ui.control.RadioButton
        MetododeBiseccionButton      matlab.ui.control.RadioButton
        MetododeFalsaPocisionButton  matlab.ui.control.RadioButton
        ContinuarButton              matlab.ui.control.Button
        ElijaelmtodoLabel            matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MetodosDropDown
        function MetodosDropDownValueChanged(app, event)
          app.ButtonGroup.Visible = 'on';
          if strcmp(app.MetodosDropDown.Value,'Abiertos')
              app.MetododeBiseccionButton.Visible = 'off';
              app.MetododeFalsaPocisionButton.Visible = 'off';
              app.MetododeNewtonRaphsonButton.Visible = 'on';
              app.MetododelaSecanteButton.Visible = 'on';
              app.PuntoFijoButton.Visible = 'on';
              app.RaicesMultiplesButton.Visible = 'on';
              app.MetododelaSecanteButton.Value = true;
          elseif strcmp(app.MetodosDropDown.Value,'Cerrados')
              app.MetododeBiseccionButton.Visible = 'on';
              app.MetododeFalsaPocisionButton.Visible = 'on';
              app.MetododeNewtonRaphsonButton.Visible = 'off';
              app.MetododelaSecanteButton.Visible = 'off';
              app.PuntoFijoButton.Visible = 'off';
              app.RaicesMultiplesButton.Visible = 'off';
              app.MetododeBiseccionButton.Position = [13,66,139,22];
              app.MetododeFalsaPocisionButton.Position = [13,44,175,22];
              app.MetododeBiseccionButton.Value = true;
          end
         
            
        end

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
            if app.MetododelaSecanteButton.Value
                interfaz13;
                delete(app);
            elseif app.MetododeNewtonRaphsonButton.Value
                interfaz11;
                delete(app);
            elseif app.PuntoFijoButton.Value
                interfaz6;
                delete(app);
            elseif app.RaicesMultiplesButton.Value
                interfaz7;
                delete(app);
            elseif app.MetododeBiseccionButton.Value
                interfaz9;
                delete(app);
            elseif app.MetododeFalsaPocisionButton.Value
                interfaz15;
                delete(app);
            end
        end

        % Button pushed function: PagPrincipalButton
        function PagPrincipalButtonPushed(app, event)
            interfaz1;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ventana2 = uifigure('Visible', 'off');
            app.ventana2.Name = 'MATLAB App';
            app.PagPrincipalButton = uibutton(app.ventana2, 'push');
            app.PagPrincipalButton.ButtonPushedFcn = createCallbackFcn(app, @PagPrincipalButtonPushed, true);
            app.PagPrincipalButton.Text = 'Pag. Principal';
            app.RaicesdeEcuacionesLabel = uilabel(app.ventana2);
            app.RaicesdeEcuacionesLabel.Text = 'Raices de Ecuaciones';
            app.Label_2 = uilabel(app.ventana2);
            app.Label_2.Text = '---------------------------------------------------------------------------------------------------------------------------------';
            app.Image = uiimage(app.ventana2);
            app.Image.ImageSource = 'raices.png';
            app.MetodosDropDownLabel = uilabel(app.ventana2);
            app.MetodosDropDownLabel.Text = 'Metodos: ';
            app.MetodosDropDown = uidropdown(app.ventana2);
            app.MetodosDropDown.Items = {'Abiertos', 'Cerrados'};
            app.MetodosDropDown.ValueChangedFcn = createCallbackFcn(app, @MetodosDropDownValueChanged, true);
            app.MetodosDropDown.Value = 'Abiertos';
            app.ButtonGroup = uibuttongroup(app.ventana2);
            app.MetododelaSecanteButton = uiradiobutton(app.ButtonGroup);
            app.MetododelaSecanteButton.Text = 'Metodo de la Secante';
            app.MetododelaSecanteButton.Value = true;
            app.MetododeNewtonRaphsonButton = uiradiobutton(app.ButtonGroup);
            app.MetododeNewtonRaphsonButton.Text = 'Metodo de Newton-Raphson';
            app.PuntoFijoButton = uiradiobutton(app.ButtonGroup);
            app.PuntoFijoButton.Text = 'Punto Fijo';
            app.RaicesMultiplesButton = uiradiobutton(app.ButtonGroup);
            app.RaicesMultiplesButton.Text = 'Raices Multiples';
            app.MetododeBiseccionButton = uiradiobutton(app.ButtonGroup);
            app.MetododeBiseccionButton.Visible = 'off';
            app.MetododeBiseccionButton.Text = 'Metodo de Bisección';
            app.MetododeFalsaPocisionButton = uiradiobutton(app.ButtonGroup);
            app.MetododeFalsaPocisionButton.Visible = 'off';
            app.MetododeFalsaPocisionButton.Text = 'Metodo de Falsa Pocisión';
            app.ContinuarButton = uibutton(app.ventana2, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            app.ElijaelmtodoLabel = uilabel(app.ventana2);
            app.ElijaelmtodoLabel.Text = 'Elija el método';
            app.Label_3 = uilabel(app.ventana2);
            app.Label_3.Text = '---------------------------------------------------------------------------------------------------------------------------------';
            % Show the figure after all components are created
            app.ventana2.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ventana2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ventana2)
        end
    end
end