classdef interfaz3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ventana3                   matlab.ui.Figure
        PagPrincipalButton         matlab.ui.control.Button
        SistemasdeEcuacionesLabel  matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        ButtonGroup                matlab.ui.container.ButtonGroup
        MetododeCramerButton       matlab.ui.control.RadioButton
        MetododeGaussButton        matlab.ui.control.RadioButton
        MetododeGaussJordanButton  matlab.ui.control.RadioButton
        MetododeJacobiButton       matlab.ui.control.RadioButton
        MetododeGaussSeidelButton  matlab.ui.control.RadioButton
        ContinuarButton            matlab.ui.control.Button
        ElijaelmtodoLabel          matlab.ui.control.Label
        MetodosDropDownLabel       matlab.ui.control.Label
        MetodosDropDown            matlab.ui.control.DropDown
        Label_3                    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MetodosDropDown
        function MetodosDropDownValueChanged(app, event)
          if strcmp(app.MetodosDropDown.Value,'Directos')
              app.MetododeCramerButton.Visible = 'on';
              app.MetododeGaussButton.Visible = 'on';
              app.MetododeGaussJordanButton.Visible = 'on';
              app.MetododeJacobiButton.Visible = 'off';
              app.MetododeGaussSeidelButton.Visible = 'off';
              app.MetododeCramerButton.Value = true;

          elseif strcmp(app.MetodosDropDown.Value,'Iterativos')
              app.MetododeCramerButton.Visible = 'off';
              app.MetododeGaussButton.Visible = 'off';
              app.MetododeGaussJordanButton.Visible = 'off';
              app.MetododeJacobiButton.Visible = 'on';
              app.MetododeGaussSeidelButton.Visible = 'on';
              app.MetododeJacobiButton.Position = [12,44,122,22];
              app.MetododeGaussSeidelButton.Position = [12,23,154,22];
              
              app.MetododeJacobiButton.Value = true;
          end
         
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            
        end

        % Button pushed function: PagPrincipalButton
        function PagPrincipalButtonPushed(app, event)
            interfaz1;
            delete(app);
        end

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
            if app.MetododeCramerButton.Value
                interfaz4;
                delete(app);
            elseif app.MetododeGaussButton.Value
                interfaz5;
                delete(app);
            elseif app.MetododeGaussJordanButton.Value
                interfaz6;
                delete(app);
            elseif app.MetododeJacobiButton.Value
                interfaz7;
                delete(app);
            elseif app.MetododeGaussSeidelButton.Value
                interfaz8;
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ventana3 = uifigure('Visible', 'off');
            app.ventana3.Name = 'MATLAB App';
            app.PagPrincipalButton = uibutton(app.ventana3, 'push');
            app.PagPrincipalButton.ButtonPushedFcn = createCallbackFcn(app, @PagPrincipalButtonPushed, true);
            app.PagPrincipalButton.Text = 'Pag. Principal';
            app.SistemasdeEcuacionesLabel = uilabel(app.ventana3);
            app.SistemasdeEcuacionesLabel.Text = 'Sistemas de Ecuaciones';
            app.Label_2 = uilabel(app.ventana3);
            app.Label_2.Text = '---------------------------------------------------------------------------------------------------------------------------------';
            app.Image = uiimage(app.ventana3);
            app.Image.ImageSource = 'Sistemas-de-ecuaciones-post.jpg';
            app.ButtonGroup = uibuttongroup(app.ventana3);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.MetododeCramerButton = uiradiobutton(app.ButtonGroup);
            app.MetododeCramerButton.Text = 'Metodo de Cramer';
            app.MetododeCramerButton.Value = true;
            app.MetododeGaussButton = uiradiobutton(app.ButtonGroup);
            app.MetododeGaussButton.Text = 'Metodo de Gauss';
            app.MetododeGaussJordanButton = uiradiobutton(app.ButtonGroup);
            app.MetododeGaussJordanButton.Text = 'Metodo de Gauss-Jordan';
            app.MetododeJacobiButton = uiradiobutton(app.ButtonGroup);
            app.MetododeJacobiButton.Visible = 'off';
            app.MetododeJacobiButton.Text = 'Metodo de Jacobi';
            app.MetododeGaussSeidelButton = uiradiobutton(app.ButtonGroup);
            app.MetododeGaussSeidelButton.Visible = 'off';
            app.MetododeGaussSeidelButton.Text = 'Metodo de Gauss-Seidel';
            app.ContinuarButton = uibutton(app.ventana3, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            app.ElijaelmtodoLabel = uilabel(app.ventana3);
            app.ElijaelmtodoLabel.Text = 'Elija el método';
            app.MetodosDropDownLabel = uilabel(app.ventana3);
            app.MetodosDropDownLabel.Text = 'Metodos: ';
            app.MetodosDropDown = uidropdown(app.ventana3);
            app.MetodosDropDown.Items = {'Directos', 'Iterativos'};
            app.MetodosDropDown.ValueChangedFcn = createCallbackFcn(app, @MetodosDropDownValueChanged, true);
            app.MetodosDropDown.Value = 'Directos';
            app.Label_3 = uilabel(app.ventana3);
            app.Label_3.Text = '---------------------------------------------------------------------------------------------------------------------------------';
            % Show the figure after all components are created
            app.ventana3.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ventana3)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ventana3)
        end
    end
end