classdef interfaz4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        PagPrincipalButton            matlab.ui.control.Button
        MtododeCramerLabel            matlab.ui.control.Label
        SeleccionarladimensindelsistemaLabel  matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        InsertarEcuacionesPanel       matlab.ui.container.Panel
        EditField                     matlab.ui.control.EditField
        Label_4                       matlab.ui.control.Label
        EditField_2                   matlab.ui.control.EditField
        Label_5                       matlab.ui.control.Label
        EditField_3                   matlab.ui.control.EditField
        EditField_4                   matlab.ui.control.EditField
        Label_6                       matlab.ui.control.Label
        EditField_5                   matlab.ui.control.EditField
        EditField_6                   matlab.ui.control.EditField
        Label_7                       matlab.ui.control.Label
        EditField_7                   matlab.ui.control.EditField
        EditField_8                   matlab.ui.control.EditField
        Label_8                       matlab.ui.control.Label
        EditField_9                   matlab.ui.control.EditField
        EditField_10                  matlab.ui.control.EditField
        nxnLabel                      matlab.ui.control.Label
        EcuacinLabel                  matlab.ui.control.Label
        igualaLabel                   matlab.ui.control.Label
        Label_18                      matlab.ui.control.Label
        Label_19                      matlab.ui.control.Label
        Label_20                      matlab.ui.control.Label
        Label_21                      matlab.ui.control.Label
        Label_22                      matlab.ui.control.Label
        ResolverSistemadeEcuacionesButton  matlab.ui.control.Button
        ResultadodelasincgnitasPanel  matlab.ui.container.Panel
        x1Label                       matlab.ui.control.Label
        Label_13                      matlab.ui.control.Label
        x2Label                       matlab.ui.control.Label
        Label_14                      matlab.ui.control.Label
        x3Label                       matlab.ui.control.Label
        Label_15                      matlab.ui.control.Label
        x4Label                       matlab.ui.control.Label
        Label_16                      matlab.ui.control.Label
        x5Label                       matlab.ui.control.Label
        Label_17                      matlab.ui.control.Label
        BorrarButton                  matlab.ui.control.Button
        AtrsButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PagPrincipalButton
        function PagPrincipalButtonPushed(app, event)
            interfaz1;
            delete(app);
        end

        % Button pushed function: AtrsButton
        function AtrsButtonPushed(app, event)
            interfaz3;
            delete(app);
        end

        % Size changed function: InsertarEcuacionesPanel
        function InsertarEcuacionesPanelSizeChanged(app, event)
  
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            if strcmp(app.DropDown.Value,'n x n')
                app.Label_18.Visible = 'off';
                app.Label_19.Visible = 'off';
                app.Label_20.Visible = 'off';
                app.Label_21.Visible = 'off';
                app.Label_22.Visible = 'off';
                app.EditField.Visible = 'off';
                app.EditField_2.Visible = 'off';
                app.EditField_3.Visible = 'off';
                app.EditField_4.Visible = 'off';
                app.EditField_5.Visible = 'off';
                app.EditField_6.Visible = 'off';
                app.EditField_7.Visible = 'off';
                app.EditField_8.Visible = 'off';
                app.EditField_9.Visible = 'off';
                app.EditField_10.Visible = 'off';
                app.Label_4.Visible = 'off';
                app.Label_5.Visible = 'off';
                app.Label_6.Visible = 'off';
                app.Label_7.Visible = 'off';
                app.Label_8.Visible = 'off';
            elseif strcmp(app.DropDown.Value,'1 x 1')
                app.Label_18.Visible = 'on';
                app.Label_19.Visible = 'off';
                app.Label_20.Visible = 'off';
                app.Label_21.Visible = 'off';
                app.Label_22.Visible = 'off';
                app.EditField.Visible = 'on';
                app.EditField_2.Visible = 'on';
                app.EditField_3.Visible = 'off';
                app.EditField_4.Visible = 'off';
                app.EditField_5.Visible = 'off';
                app.EditField_6.Visible = 'off';
                app.EditField_7.Visible = 'off';
                app.EditField_8.Visible = 'off';
                app.EditField_9.Visible = 'off';
                app.EditField_10.Visible = 'off';
                app.Label_4.Visible = 'on';
                app.Label_5.Visible = 'off';
                app.Label_6.Visible = 'off';
                app.Label_7.Visible = 'off';
                app.Label_8.Visible = 'off';
            
            elseif strcmp(app.DropDown.Value,'2 x 2')
                app.Label_18.Visible = 'on';
                app.Label_19.Visible = 'on';
                app.Label_20.Visible = 'off';
                app.Label_21.Visible = 'off';
                app.Label_22.Visible = 'off';
                app.EditField.Visible = 'on';
                app.EditField_2.Visible = 'on';
                app.EditField_3.Visible = 'on';
                app.EditField_4.Visible = 'on';
                app.EditField_5.Visible = 'off';
                app.EditField_6.Visible = 'off';
                app.EditField_7.Visible = 'off';
                app.EditField_8.Visible = 'off';
                app.EditField_9.Visible = 'off';
                app.EditField_10.Visible = 'off';
                app.Label_4.Visible = 'on';
                app.Label_5.Visible = 'on';
                app.Label_6.Visible = 'off';
                app.Label_7.Visible = 'off';
                app.Label_8.Visible = 'off';
                
            elseif strcmp(app.DropDown.Value,'3 x 3')
                app.Label_18.Visible = 'on';
                app.Label_19.Visible = 'on';
                app.Label_20.Visible = 'on';
                app.Label_21.Visible = 'off';
                app.Label_22.Visible = 'off';
                app.EditField.Visible = 'on';
                app.EditField_2.Visible = 'on';
                app.EditField_3.Visible = 'on';
                app.EditField_4.Visible = 'on';
                app.EditField_5.Visible = 'on';
                app.EditField_6.Visible = 'on';
                app.EditField_7.Visible = 'off';
                app.EditField_8.Visible = 'off';
                app.EditField_9.Visible = 'off';
                app.EditField_10.Visible = 'off';
                app.Label_4.Visible = 'on';
                app.Label_5.Visible = 'on';
                app.Label_6.Visible = 'on';
                app.Label_7.Visible = 'off';
                app.Label_8.Visible = 'off';
            
            elseif strcmp(app.DropDown.Value,'4 x 4')
                app.Label_18.Visible = 'on';
                app.Label_19.Visible = 'on';
                app.Label_20.Visible = 'on';
                app.Label_21.Visible = 'on';
                app.Label_22.Visible = 'off';
                app.EditField.Visible = 'on';
                app.EditField_2.Visible = 'on';
                app.EditField_3.Visible = 'on';
                app.EditField_4.Visible = 'on';
                app.EditField_5.Visible = 'on';
                app.EditField_6.Visible = 'on';
                app.EditField_7.Visible = 'on';
                app.EditField_8.Visible = 'on';
                app.EditField_9.Visible = 'off';
                app.EditField_10.Visible = 'off';
                app.Label_4.Visible = 'on';
                app.Label_5.Visible = 'on';
                app.Label_6.Visible = 'on';
                app.Label_7.Visible = 'on';
                app.Label_8.Visible = 'off';
            elseif strcmp(app.DropDown.Value,'5 x 5')
                app.Label_18.Visible = 'on';
                app.Label_19.Visible = 'on';
                app.Label_20.Visible = 'on';
                app.Label_21.Visible = 'on';
                app.Label_22.Visible = 'on';
                app.EditField.Visible = 'on';
                app.EditField_2.Visible = 'on';
                app.EditField_3.Visible = 'on';
                app.EditField_4.Visible = 'on';
                app.EditField_5.Visible = 'on';
                app.EditField_6.Visible = 'on';
                app.EditField_7.Visible = 'on';
                app.EditField_8.Visible = 'on';
                app.EditField_9.Visible = 'on';
                app.EditField_10.Visible = 'on';
                app.Label_4.Visible = 'on';
                app.Label_5.Visible = 'on';
                app.Label_6.Visible = 'on';
                app.Label_7.Visible = 'on';
                app.Label_8.Visible = 'on';
                
            end
            
        end

        % Button pushed function: BorrarButton
        function BorrarButtonPushed(app, event)
           if strcmp(app.DropDown.Value,'1 x 1')
               app.EditField.Value = " ";
               app.EditField_2.Value = " ";
           elseif strcmp(app.DropDown.Value,'2 x 2')
               app.EditField.Value = " ";
               app.EditField_2.Value = " ";
               app.EditField_3.Value = " ";
               app.EditField_4.Value = " ";
           elseif strcmp(app.DropDown.Value,'3 x 3')
               app.EditField.Value = " ";
               app.EditField_2.Value = " ";
               app.EditField_3.Value = " ";
               app.EditField_4.Value = " ";
               app.EditField_5.Value = " ";
               app.EditField_6.Value = " ";
           elseif strcmp(app.DropDown.Value,'4 x 4')
               app.EditField.Value = " ";
               app.EditField_2.Value = " ";
               app.EditField_3.Value = " ";
               app.EditField_4.Value = " ";
               app.EditField_5.Value = " ";
               app.EditField_6.Value = " ";
               app.EditField_7.Value = " ";
               app.EditField_8.Value = " ";
           elseif strcmp(app.DropDown.Value,'5 x 5')
               app.EditField.Value = " ";
               app.EditField_2.Value = " ";
               app.EditField_3.Value = " ";
               app.EditField_4.Value = " ";
               app.EditField_5.Value = " ";
               app.EditField_6.Value = " ";
               app.EditField_7.Value = " ";
               app.EditField_8.Value = " ";
               app.EditField_9.Value = " ";
               app.EditField_10.Value = " ";
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '---------------------------------------------------------------------------------------------------------------------------------';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '---------------------------------------------------------------------------------------------------------------------------------';
            app.PagPrincipalButton = uibutton(app.UIFigure, 'push');
            app.PagPrincipalButton.ButtonPushedFcn = createCallbackFcn(app, @PagPrincipalButtonPushed, true);
            app.PagPrincipalButton.Text = 'Pag. Principal';
            app.MtododeCramerLabel = uilabel(app.UIFigure);
            app.MtododeCramerLabel.Text = 'Método de Cramer ';
            app.SeleccionarladimensindelsistemaLabel = uilabel(app.UIFigure);
            app.SeleccionarladimensindelsistemaLabel.Text = 'Seleccionar la dimensión del sistema:';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'n x n', '1 x 1', '2 x 2', '3 x 3', '4 x 4', '5 x 5'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'n x n';
            app.InsertarEcuacionesPanel = uipanel(app.UIFigure);
            app.InsertarEcuacionesPanel.Title = 'Insertar Ecuaciones ';
            app.InsertarEcuacionesPanel.SizeChangedFcn = createCallbackFcn(app, @InsertarEcuacionesPanelSizeChanged, true);
            app.EditField = uieditfield(app.InsertarEcuacionesPanel, 'text');
            app.Label_4 = uilabel(app.InsertarEcuacionesPanel);
            app.Label_4.Text = '=';
            app.EditField_2 = uieditfield(app.InsertarEcuacionesPanel, 'text');
            app.Label_5 = uilabel(app.InsertarEcuacionesPanel);
            app.Label_5.Text = '=';
            app.EditField_3 = uieditfield(app.InsertarEcuacionesPanel, 'text');
            app.EditField_4 = uieditfield(app.InsertarEcuacionesPanel, 'text');
            app.Label_6 = uilabel(app.InsertarEcuacionesPanel);
            app.Label_6.Text = '=';
            app.EditField_5 = uieditfield(app.InsertarEcuacionesPanel, 'text');
            app.EditField_6 = uieditfield(app.InsertarEcuacionesPanel, 'text');
            app.Label_7 = uilabel(app.InsertarEcuacionesPanel);
            app.Label_7.Text = '=';
            app.EditField_7 = uieditfield(app.InsertarEcuacionesPanel, 'text');
            app.EditField_8 = uieditfield(app.InsertarEcuacionesPanel, 'text');
            app.Label_8 = uilabel(app.InsertarEcuacionesPanel);
            app.Label_8.Text = '=';
            app.EditField_9 = uieditfield(app.InsertarEcuacionesPanel, 'text');
            app.EditField_10 = uieditfield(app.InsertarEcuacionesPanel, 'text');
            app.nxnLabel = uilabel(app.InsertarEcuacionesPanel);
            app.nxnLabel.Text = 'nxn';
            app.EcuacinLabel = uilabel(app.InsertarEcuacionesPanel);
            app.EcuacinLabel.Text = 'Ecuación';
            app.igualaLabel = uilabel(app.InsertarEcuacionesPanel);
            app.igualaLabel.Text = 'igual a';
            app.Label_18 = uilabel(app.InsertarEcuacionesPanel);
            app.Label_18.Text = '1';
            app.Label_19 = uilabel(app.InsertarEcuacionesPanel);
            app.Label_19.Text = '2';
            app.Label_20 = uilabel(app.InsertarEcuacionesPanel);
            app.Label_20.Text = '3';
            app.Label_21 = uilabel(app.InsertarEcuacionesPanel);
            app.Label_21.Text = '4';
            app.Label_22 = uilabel(app.InsertarEcuacionesPanel);
            app.Label_22.Text = '5';
            app.ResolverSistemadeEcuacionesButton = uibutton(app.UIFigure, 'push');
            app.ResolverSistemadeEcuacionesButton.Text = 'Resolver Sistema de Ecuaciones';
            app.ResultadodelasincgnitasPanel = uipanel(app.UIFigure);
            app.ResultadodelasincgnitasPanel.Title = 'Resultado de las incógnitas';
            app.x1Label = uilabel(app.ResultadodelasincgnitasPanel);
            app.x1Label.Text = 'x1 = ';
            app.Label_13 = uilabel(app.ResultadodelasincgnitasPanel);
            app.Label_13.Text = '';
            app.x2Label = uilabel(app.ResultadodelasincgnitasPanel);
            app.x2Label.Text = 'x2 = ';
            app.Label_14 = uilabel(app.ResultadodelasincgnitasPanel);
            app.Label_14.Text = '';
            app.x3Label = uilabel(app.ResultadodelasincgnitasPanel);
            app.x3Label.Text = 'x3 = ';
            app.Label_15 = uilabel(app.ResultadodelasincgnitasPanel);
            app.Label_15.Text = '';
            app.x4Label = uilabel(app.ResultadodelasincgnitasPanel);
            app.x4Label.Text = 'x4 = ';
            app.Label_16 = uilabel(app.ResultadodelasincgnitasPanel);
            app.Label_16.Text = '';
            app.x5Label = uilabel(app.ResultadodelasincgnitasPanel);
            app.x5Label.Text = 'x5 = ';
            app.Label_17 = uilabel(app.ResultadodelasincgnitasPanel);
            app.Label_17.Text = '';
            app.BorrarButton = uibutton(app.UIFigure, 'push');
            app.BorrarButton.ButtonPushedFcn = createCallbackFcn(app, @BorrarButtonPushed, true);
            app.BorrarButton.Text = 'Borrar';
            app.AtrsButton = uibutton(app.UIFigure, 'push');
            app.AtrsButton.ButtonPushedFcn = createCallbackFcn(app, @AtrsButtonPushed, true);
            app.AtrsButton.Text = 'Atrás';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end